classdef draw_ROIs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ExitButton                  matlab.ui.control.Button
        MotioncorrectionButton      matlab.ui.control.Button
        Slider                      matlab.ui.control.Slider
        Label                       matlab.ui.control.Label
        showCommand                 matlab.ui.control.EditField
        AddNewROIButton             matlab.ui.control.Button
        LoadImageSeriesButton       matlab.ui.control.Button
        LoadROIsButton              matlab.ui.control.Button
        SavePanel                   matlab.ui.container.Panel
        SaveButton                  matlab.ui.control.Button
        saveROIpngCheckBox          matlab.ui.control.CheckBox
        saveROIsvgCheckBox          matlab.ui.control.CheckBox
        savetracepngCheckBox        matlab.ui.control.CheckBox
        savetracesvgCheckBox        matlab.ui.control.CheckBox
        saveTracescsvCheckBox       matlab.ui.control.CheckBox
        CurrentROIPanel             matlab.ui.container.Panel
        CurrentROIDropDown          matlab.ui.control.DropDown
        ChangeOrderLabel            matlab.ui.control.Label
        upButton                    matlab.ui.control.Button
        downButton                  matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        DeleteROILabel              matlab.ui.control.Label
        ProjectionDropDown          matlab.ui.control.DropDown
        fpsLabel                    matlab.ui.control.Label
        currentimageseriesfpsLabel  matlab.ui.control.Label
        LabelDropDown               matlab.ui.control.DropDown
        DropDown_3Label             matlab.ui.control.Label
        UpdateLabel                 matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        meanimage %mean projection of image stack
        image %combined mean (blue/green) + variance (red)
        video %full image series
        image_each_f %time series projection of image stack (controlled by slide)
        dir %directory of image series
        savedir %directory to save output files
        filename %name of image series
        fps %frames/sec of full image series
        rois %vectors of vertices and logical masks for each roi
        visibilities %determines visibility of of image-related and ROI-related buttons/dropdowns/etc.
        currentROI %currently selected ROI
    end

    methods (Access = private)
    
        function update(app)
            if strcmp(app.visibilities{1},'on') %i.e. image file has been loaded
                
                %show image projection
                cla(app.UIAxes)
                projection_type = app.ProjectionDropDown.Value;
                if strcmp(projection_type,'mean projection')
                    imshow(app.meanimage,'Parent',app.UIAxes);
                    title(app.UIAxes,'\fontsize{16}Mean Projection')
                elseif strcmp(projection_type,'mean + variance')
                    imshow(app.image,'Parent',app.UIAxes);
                    title(app.UIAxes,['\fontsize{16}Image Projection (', '\color[rgb]{0 0.7 0.7}mean','\color[rgb]{0 0 0}+','\color[rgb]{1 0 0}variance','\color[rgb]{0 0 0})'],'interpreter','tex')
                elseif strcmp(projection_type,'time series')
                    a = app.video(:,:,3);
                    imshow(app.image_each_f(:,:,floor(app.Slider.Value)),'Parent',app.UIAxes);
                    title(app.UIAxes,'\fontsize{16}time series')
                end
                hold(app.UIAxes,'on');
                app.UIAxes.XLim = [0 size(app.image,2)];
                app.UIAxes.YLim = [0 size(app.image,1)];
                
                n = app.rois.num; %number of ROIs
                if n==0
                    app.visibilities = {'on','off'}; %image file is loaded, but no ROIs have been drawn
                                    
                else
                    app.visibilities = {'on','on'}; %image file is loaded, and at least 1 ROI is drawn
                    
                    %update ROI dropdown
                    labels = cellstr(num2str((1:n)'));
                    app.CurrentROIDropDown.Items = labels;
                    app.CurrentROIDropDown.ItemsData = labels;
                    app.CurrentROIDropDown.Value = labels{app.currentROI};
                    
                    %draw ROI polygons
                    lw = 2;
                    color = linspecer(n); %beautiful color set
                    for r=1:n
                        pl = plot(app.rois.x(r,:),app.rois.y(r,:),'Color',color(r,:),'Parent',app.UIAxes,'LineWidth',lw);
                        p(r) = pl;
                    end
                    
                    %add ROI labels
                    roilabels = app.LabelDropDown.Value;
                    if strcmp(roilabels,'labels in legend')
                        lg = legend(p,labels,'Location','NorthEastOutside'); 
                        title(lg,'ROIs')
                        set(lg,'LineWidth',lw)
                        set(lg,'FontWeight','bold')
                    else %write ROI text on image
                        for r=1:n
                            text(nanmean(app.rois.x(r,:)),nanmean(app.rois.y(r,:)),labels{r},'Color',color(r,:),...
                                'FontWeight','bold','Parent',app.UIAxes,'LineWidth',lw,'HorizontalAlignment','center','VerticalAlignment','middle');
                            lg = legend(app.UIAxes,labels);
                            set(lg,'Visible','off');
                        end
                    end
                end
            end
            app.updateVisibility(); 
            %app.UpdateLabel.Text = ''; %finished updating
            
        end

        function updateVisibility(app)
            
            %set visibility of image-related fields
            app.UIAxes.Visible = app.visibilities{1};
            app.AddNewROIButton.Visible = app.visibilities{1};
            app.fpsLabel.Visible = app.visibilities{1};
            app.currentimageseriesfpsLabel.Visible = app.visibilities{1};
            app.LoadROIsButton.Visible = app.visibilities{1};
            app.ProjectionDropDown.Visible = app.visibilities{1};
            app.Slider.Visible = app.visibilities{1};
            app.MotioncorrectionButton.Visible = app.visibilities{1};
            
            %set visibility of ROI-related fields
            app.CurrentROIPanel.Visible = app.visibilities{2};
            app.SavePanel.Visible = app.visibilities{2};
            app.LabelDropDown.Visible = app.visibilities{2};   
        end
        
        function iseries = loadImage(app, source, getfps)
            % load full image series
            [app.dir,app.filename,ext] = fileparts(source);
            app.savedir = [app.dir '\Analysis ' app.filename];
            if ~exist(app.savedir,'dir')
                mkdir(app.savedir)
            end
            if ~strcmp(ext,'.tif') && ~strcmp(ext,'.tiff')
                app.UpdateLabel.Text = 'Image file format must be ".tif" or "tiff" ';
                app.visibilities = {'off','off'};
            else
                app.UpdateLabel.Text = 'Getting file info...';
                try
                    info = imfinfo(source);
                    im_size = [info(1).Height, info(1).Width, length(info)];
                catch
                    im_size = [128, 128, 1600];
                end
                if im_size(3)==1
                    app.UpdateLabel.Text = 'The selected .tif file only contains 1 frame.';
                    pause(2) %pause for message to be read
                end
                iseries = nan(im_size);
                for f = 1:im_size(3)
                    if mod(f,10)==0
                        app.UpdateLabel.Text = ['Loading image series: frame ' num2str(f) ' of ' num2str(im_size(3))]; 
                    end
                    iseries(:,:,f) = imread(source,f);
                end
                
                %get image series fps
                if im_size(3)>1
                    t = Tiff(source,'r');
                    readfps = read_tiffstate(t,'frameRate');
                    if isnan(readfps)
                        if getfps==1
                            readfps = str2double(inputdlg('Enter image series frame rate:'));
                        end
                    end
                    if getfps==1
                        app.fps=readfps;
                    else
                        if readfps~=app.fps
                            app.UpdateLabel.Text = ['fps in tif file metadata (' num2str(readfps) ') doesn''t match current fps (' num2str(app.fps)];
                            pause(2);
                        end
                    end
                else
                    app.fps = 0;
                end
                
                app.fpsLabel.Text = num2str(app.fps);
                app.UpdateLabel.Text = '';
            end
            
        end
        
        function processImage(app, iseries)
            app.UpdateLabel.Text = 'Processing images...';
            
            %create image projections of both mean and variance to aid in ROI selection
            im_size = size(iseries);
            if length(im_size)>2
                nf = im_size(3);
            else
                nf = 1;
            end
            im_mean = nanmean(iseries,3);
            im_var = nanmean((iseries-repmat(im_mean,[1 1 nf])).^2,3);
            im_mean_scaled = im_mean/prctile(reshape(im_mean,[1 numel(im_mean)]),99); %~0-1 range
            im_var_scaled = im_var./prctile(reshape(im_var,[1 numel(im_var)]),99); %~0-1 range
            app.image(:,:,1) = im_var_scaled; %image variance in red channel
            app.image(:,:,2) = im_mean_scaled; %mean image in blue and green channels
            app.image(:,:,3) = im_mean_scaled;
            app.meanimage = im_mean_scaled;
            
            %~0-1 range for each frame
            for i = 1 : nf
                
                app.image_each_f(:,:,i) = mat2gray(iseries(:,:,i));
                
            end
                
            if nf>1
                %save mean and variance images
                imwrite(im_mean_scaled,fullfile(app.savedir,['mean_' app.filename '.tif']),'tiff');
                imwrite(im_var_scaled,fullfile(app.savedir,['var_' app.filename '.tif']),'tiff');
            end
            app.video = iseries;
            app.visibilities = {'on','off'};
            app.UpdateLabel.Text = '';
            app.Slider.Limits = [1,nf];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source, fps)
            warning('off')
            
            %make certain fields invisible until they become relevant
            app.visibilities = {'off','off'}; 
            app.updateVisibility();
            
            %initialize ROI variables
            app.rois.num = 0;
            app.rois.roi = nan(1,1,1);
            app.rois.x = nan(1,1);
            app.rois.y = nan(1,1);
            
            %handle inputs
            if nargin==1 %no file or fps given
                app.visibilities = {'off','off'};
                
            elseif nargin==2 %image file given, but no fps
                app.visibilities = {'on','off'};
                getfps = 1;                  
                
            elseif nargin==3 %image file and fps given
                app.visibilities = {'on','off'};
                if ~isempty(fps) && ~isnan(fps)
                    app.fps = fps;
                    getfps = 0;
                else
                    getfps = 1;
                end
            end
            
            if nargin>1
                if ischar(class(source)) %source is a directory of an image file                
                    iseries = app.loadImage(source,getfps); %load image series
                    app.processImage(iseries); %load process series
                    
                else %source is an image matrix (no filename or path)
                    [app.dir, ~, ~] = fileparts(mfilename('fullpath') );
                    app.filename = ['ImageSeries_' datestr(now,'yyyy-mm-dd_HH-MM-SS')];
                    app.processImage(source);                
                end  
            end
            
            app.update;
        end

        % Button pushed function: AddNewROIButton
        function AddNewROIButtonPushed(app, event)
            
            app.UpdateLabel.Text = 'Waiting for user-drawn ROI...';
            
            %get user's screen size to create a large figure window
            screenSize = get(groot,'ScreenSize');
            left = screenSize(3)*0.2;
            bottom = screenSize(4)*0.1;
            width = screenSize(3)*0.6;
            height = screenSize(4)*0.8;
            
            %create new figure window for drawing an ROI
            drawfigure = figure('Name','draw ROI','NumberTitle','off');
            drawaxis = axes(drawfigure);
            projection_type = app.ProjectionDropDown.Value;
            if strcmp(projection_type,'mean projection')
                imshow(app.meanimage,'Parent',drawaxis);
            elseif strcmp(projection_type,'mean + variance')
                imshow(app.image,'Parent',drawaxis);
            elseif strcmp(projection_type,'time series')
                imshow(app.image_each_f(:,:,floor(app.Slider.Value)),'Parent',drawaxis);
            end
            hold on
            set(drawfigure,'Position',[left bottom width height]);
            set(drawaxis,'OuterPosition',[0 0 1 1]);
            title(drawaxis,'Draw a polygonal ROI (double-click starting vertex to close)');
            
            %draw all ROI polygons made so far
            n = app.rois.num;
            if n>0
                color = linspecer(n); %"beautiful color set"
                for r=1:n
                    plot(app.rois.x(r,:),app.rois.y(r,:),'Color',color(r,:),'Parent',drawaxis,'LineWidth',2);
                end
            end
            drawnow
            
            %have user draw a new ROI polygon
            try
                [roi, x, y] = roipoly;
            catch %if roipoly figure was exited incorrectly
                app.UpdateLabel.Text = '"Add ROI" exited';
                pause(1)
                app.UpdateLabel.Text = '';
                return
            end
            if exist('roi','var') && isempty(roi) %if no ROI is drawn
                app.UpdateLabel.Text = 'no ROI drawn';
                pause(1)
                app.UpdateLabel.Text = '';
                return;
            end
            
            close(drawfigure)
            app.rois.num = app.rois.num+1;
            n = app.rois.num;
            app.currentROI = n;
            [h,w] = size(roi);
            
            %fill empty space in roi.x and roi.y with nans (not all ROIs have the same number of vertices)
            nv = length(x); %num of vertices of new roi
            pv = size(app.rois.x,2); %previous largest num of vertices
            if nv>pv
                app.rois.x(:,pv+1:nv) = nan;
                app.rois.y(:,pv+1:nv) = nan;
            else
                app.rois.x(n,:) = nan;
                app.rois.y(n,:) = nan;
            end
            
            %add new ROI to full list
            app.rois.roi(1:h,1:w,n) = roi;
            app.rois.x(n,1:nv) = x;
            app.rois.y(n,1:nv) = y;
            
            app.update;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            currentROI = str2double(app.CurrentROIDropDown.Value);
            if isempty(currentROI)
                app.UpdateLabel.Text = 'Cannot delete: no ROI selected';
                pause(2); %pause for message to be read
                app.UpdateLabel.Text = '';
            else %delete the selected ROI
                app.rois.num = app.rois.num-1;                
                app.rois.roi(:,:,app.currentROI) = [];
                app.rois.x(currentROI,:) = [];
                app.rois.y(currentROI,:) = [];
                app.currentROI = app.rois.num;
                
                if app.rois.num==0
                    lg = legend(app.UIAxes,'');
                    set(lg,'Visible','off');
                end
                
                app.update;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            n = app.rois.num;
            if n>0
                app.UpdateLabel.Text = ['Saving files in ' app.savedir '...'];
                ROIs = app.rois;
                
                %get screen size for creating figure windows
                screenSize = get(groot,'ScreenSize');
                left = screenSize(3)*0.25;
                bottom = screenSize(4)*0.1;
                width = screenSize(3)*0.5;
                height = screenSize(4)*0.8;
               
                
                %create a new figure to print a final picture of all drawn ROIs
                if app.saveROIpngCheckBox.Value||app.saveROIsvgCheckBox.Value
%                     drawfigure = figure('Position',[left bottom width height]);
%                     drawfigure.Visible = 'on';
%                     drawaxis = axes(drawfigure);
%                     imshow(app.meanimage,'Parent',drawaxis); %print mean image
%                     title(drawaxis,'Mean Projection')
%                     set(drawfigure,'Position',[left bottom width height]);
%                     set(drawaxis,'OuterPosition',[0 0 1 1]);
%                     drawnow
                    gcf = figure;
                    imshow(app.meanimage,'Parent',axes(gcf)); %print mean image
                    set(gcf,'Position',[left bottom width height]);
                    %set(drawaxis,'OuterPosition',[0 0 1 1]);
                    a = gca;
                    hold(a,'on');
                    axis off
                    lw = 2;
                    colors = linspecer(n);
                    for r=1:n %draw all ROIs
                        pl = plot(app.rois.x(r,:),app.rois.y(r,:),'Color',colors(r,:),'Parent',a,'LineWidth',lw);
                        p(r) = pl;
                    end
                    labels = cellstr(num2str((1:n)'));
                    roilabels = app.LabelDropDown.Value; 
                    if strcmp(roilabels,'labels in legend') %create legend for ROIs
                        lg = legend(p,labels,'Location','NorthEastOutside'); 
                        title(lg,'ROIs')
                        set(lg,'LineWidth',lw)
                        set(lg,'FontWeight','bold')
                    else %print ROI labels directly on image
                        for r=1:n
                            text(nanmean(app.rois.x(r,:)),nanmean(app.rois.y(r,:)),labels{r},'Color',colors(r,:),'Parent',a,'LineWidth',lw,'HorizontalAlignment','center');
                        end
                    end
                    if app.saveROIpngCheckBox.Value
                        exportgraphics(gcf,fullfile(app.savedir,['rois_' app.filename '.png']))
                        %saveas(drawfigure,fullfile(app.savedir,['rois_' app.filename '.png']));
                    end
                    if app.saveROIsvgCheckBox.Value
                        exportgraphics(gcf,fullfile(app.savedir,['rois_' app.filename '.pdf']),'ContentType','vector',...
                            'BackgroundColor','none')
%                         saveas(drawfigure,fullfile(app.savedir,['rois_' app.filename '.svg']));
%                         saveas(drawfigure,fullfile(app.savedir,['rois_' app.filename '.pdf']));
                    end
                    pause(0.1)
                    delete(gcf)
                end
                
                %calculate mean fluorescence traces (i.e. vectors) for each ROI
                num_frames = size(app.video,3);
                traces.raw = nan(ROIs.num, num_frames);
                for r = 1:n
                    for f = 1:num_frames
                        frame = app.video(:,:,f);
                        vec = frame(logical(ROIs.roi(:,:,r))); %logical mask for current ROI
                        traces.raw(r,f) = nanmean(vec); %get mean brightness value in ROI for every frame
                    end
                end
                traces.fps = app.fps;
                
                %create a new figure to print a final picture of ROI intensity traces
                if num_frames>1 && (app.savetracepngCheckBox.Value || app.savetracesvgCheckBox.Value)
                    if app.fps==0 || isnan(app.fps) || isempty(app.fps)
                        x = 1:num_frames;
                        xlab = 'frame';
                    elseif app.fps>0
                        x = 0:1/app.fps:(num_frames-1)/app.fps;
                        xlab = 'time (s)';
                    elseif app.fps<0
                        [file, path] = uigetfile([app.dir,'\*.txt'],'Choose .txt file to load time label');
                        [x_time_ms,sti_info] = importfile(fullfile(path,file));
                        x = x_time_ms / 1000;
                        xlab = 'time (s)';
                    end
    
%                     drawfigure = figure('Position',[left bottom width height]);
%                     drawfigure.Visible = 'off';
%                     drawaxis = axes(drawfigure);
%                     title(drawaxis,'mean intensity traces','FontSize',16)
%                     set(drawfigure,'Position',[left bottom width height]);
%                     set(drawaxis,'OuterPosition',[0 0 1 1]);
%                     drawnow
                    gcf = figure;
                    a = gca;
                    hold(a,'on');
                    
%                     delta = (traces.raw./repmat(nanmean(traces.raw,2),[1 num_frames]))-1;
%                     max_range = 4*(prctile(reshape(delta,[1 numel(delta)]),97));
%                     for r = 1:n
%                         trace = delta(r,:)+(r-0.5)*max_range;
%                         line(x,ones(size(x))*(r-0.5)*max_range,'Color','black','LineStyle','--')
%                         hold on
%                         plot(x,trace,'Color','b','LineWidth',1);
%                     end


                    % calculate the ΔF/F0, the baseline is chosen within [0.02*frame_num,0.05*frame_num]
                    try
                        delta = (traces.raw./repmat(nanmean(traces.raw(:,floor(f*0.02):floor(f*0.05)),2),[1 num_frames]))-1;
                    catch
                        delta = (traces.raw./repmat(nanmean(traces.raw(:,1:4),2),[1 num_frames]))-1;
                    end
                    
                    for r = 1:n
                            trace = delta(r,:);
                            hold on
                            pl = plot(x,trace,'Color',colors(r,:),'LineWidth',1);
                            p(r) = pl;
                    end
                    
                    % plot the shaded region as stimulation time if necessary
                    if app.fps < 0
                        opto_sti_index = find(~cellfun(@isempty,sti_info) == 1);
                        for i = 1 : length(opto_sti_index)
                            shaded_index = opto_sti_index(i);
                            patch([x(shaded_index) x(shaded_index+1) x(shaded_index+1) x(shaded_index)], ...
                                [min(ylim) min(ylim), max(ylim) max(ylim)],'black','FaceAlpha',.05)
                            hold on
                            % add the stimulation text
                            s = split(sti_info(shaded_index));
                            if length(s) > 12
                                str = {[s{5,1},'ms*',s{7,1}],['power',s{13,1}]};
                            else
                                str = {[s{5,1},'ms*',s{7,1}]};
                            end                         
                            text(x(shaded_index),max(ylim),str,'FontSize',6);
                        end                    
                    end
                    
                    labels = cellstr(num2str((1:n)'));
                    lg = legend(p,labels,'Location','NorthEastOutside'); 
                    title(lg,'ROIs')
                    set(lg,'LineWidth',lw)
                    set(lg,'FontWeight','bold')
                    xlim([0 x(end)])
                    %ylim([0 n*max_range])
                    %yticks((0.5:1:n)*max_range)
                    %yticklabels(1:n)
                    a.YAxis.FontSize = 12;
                    a.XAxis.FontSize = 12;
                    xlabel(xlab,'FontSize',14)
                    ylabel('{\Delta}F/F','FontSize',14)
                    box off
                    set(a,'linewidth',2)
                    
                    %plot y scale
%                     r_max_range = round(max_range*10)/10;
%                     scaleaxis = axes(drawfigure);
%                     set(scaleaxis,'OuterPosition',[0.05 0 0.05 1]);
%                     plot([0 0],[max_range*0.5, (max_range*0.5)+r_max_range],'k','LineWidth',1)
%                     ylim([0 n*max_range])
%                     xlim([-0.5 0.5])
%                     tx = text(-0.7,max_range*0.5+(r_max_range/2),[num2str(r_max_range*100) '% dF/F'],'FontSize',8,'HorizontalAlignment','center','VerticalAlignment','bottom');
%                     set(tx,'Rotation',90,'FontSize',12)
%                     box off
%                     axis off
                    
                    if app.saveROIpngCheckBox.Value
                        exportgraphics(gcf,fullfile(app.savedir,['traces_' app.filename '.png']))
                        %saveas(drawfigure,fullfile(app.savedir,['rois_' app.filename '.png']));
                    end
                    if app.saveROIsvgCheckBox.Value
                        exportgraphics(gcf,fullfile(app.savedir,['traces_' app.filename '.pdf']),'ContentType','vector',...
                            'BackgroundColor','none')
%                         saveas(drawfigure,fullfile(app.savedir,['rois_' app.filename '.svg']));
%                         saveas(drawfigure,fullfile(app.savedir,['rois_' app.filename '.pdf']));
                    end
                    pause(0.1)
                    delete(gcf)
                end
                
                if app.saveTracescsvCheckBox.Value
                    R = traces.raw';
                    R = array2table(R);
                    writetable(R,fullfile(app.savedir,['Time Trace(s)' , '.csv']))
                end
                
                %save .mat files of ROIs (vertices and logical masks) and brightness traces
                save(fullfile(app.savedir,['rois_' app.filename '.mat']),'ROIs');
                save(fullfile(app.savedir,['traces_' app.filename '.mat']),'traces');
                
                app.UpdateLabel.Text = ['Done'];
            end
           

        end

        % Button pushed function: LoadROIsButton
        function LoadROIsButtonPushed(app, event)
            [file, path] = uigetfile('*.mat;*.zip','Select ROIs*.mat or RoiSet*.zip file to load',app.dir);
            
            if exist(fullfile(path,file),'file')
                %%%% fileparts recognize .zip or .mat
                [~,~,ext] = fileparts(fullfile(path,file));
                if isequal(ext,'.mat') % roi information is in the .mat
                    load(fullfile(path,file),'ROIs');
                    app.rois = ROIs;
                    app.currentROI = 1;
                    app.update;
                elseif isequal(ext,'.zip') % convert the information in the .zip to the same format in .mat 
                    imagej_roi = ReadImageJROI(fullfile(path,file));
                    ROIs.num = length(imagej_roi);
                    ROIs.roi = [];
                    ROIs.x = [];
                    ROIs.y = [];
                    for roi_index = 1 : ROIs.num
                        [BW,xi2,yi2]=roipoly(app.meanimage,imagej_roi{1, roi_index}.mnCoordinates(:,1),imagej_roi{1, roi_index}.mnCoordinates(:,2));
                        BW = BW + 0;
                        ROIs.roi = cat(3,ROIs.roi,BW);
                        
                        %fill empty space in roi.x and roi.y with nans (not all ROIs have the same number of vertices)
                        nv = length(xi2); %num of vertices of new roi
                        pv = size(ROIs.x,2); %previous largest num of vertices
                        if nv>pv && ~isempty(ROIs.x)
                            ROIs.x(:,pv+1:nv) = nan;
                            ROIs.y(:,pv+1:nv) = nan;
                        elseif nv<pv
                            xi2(nv+1:pv,:) = nan;
                            yi2(nv+1:pv,:) = nan;
                        end
                      
                        ROIs.x = cat(1,ROIs.x,xi2');
                        ROIs.y = cat(1,ROIs.y,yi2');
                    end
                    app.rois = ROIs;
                    app.currentROI = 1;
                    app.update;
                end
            end
        end

        % Value changed function: LabelDropDown
        function LabelDropDownValueChanged(app, event)
            app.update;
        end

        % Button pushed function: LoadImageSeriesButton
        function LoadImageSeriesButtonPushed(app, event)
            %get image series filename and directory
            if isempty(app.savedir)
                [file, path] = uigetfile('*.tif;*.tiff','Choose .tif or .tiff file for adding ROIs');
            else
                %[file, path] = uigetfile([app.dir,'\*.tif;\*.tiff'],'Choose .tif or .tiff file for adding ROIs');
                [file, path] = uigetfile('*.tif;*.tiff','Choose .tif or .tiff file for adding ROIs',app.dir);
            end
            if exist(fullfile(path,file),'file')
                app.dir = path;
                [~, app.filename, ~] = fileparts(file);
                source = fullfile(path,file);
                
                % load the data as iseries
                iseries = app.loadImage(source,1);
                
                % motion correction
                msg = 'Do motion correction for the image series?';
                title = 'Confirm motion correction';
                selection = uiconfirm(app.UIFigure,msg,title,...
                    'Options',{'Yes','No','Cancel'},...
                    'DefaultOption',2,'CancelOption',3);
                
                if isequal(selection,'Yes')
                    pause(0.1)
                    app.showCommand.Value = 'Do motion correction...';
                    pause(0.1)
                    options_nonrigid = NoRMCorreSetParms('d1',size(iseries,1),'d2',size(iseries,2),'grid_size',[32,32],'mot_uf',4,'bin_width',200,'max_shift',15,'max_dev',3,'us_fac',50,'init_batch',200);
                    [iseries] = normcorre_batch(iseries,options_nonrigid);
                    app.showCommand.Value = 'Motion correction done!';
                end
                
                app.processImage(iseries);
                app.update();
                
            end
        end

        % Value changed function: ProjectionDropDown
        function ProjectionDropDownValueChanged(app, event)
            app.update;
        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            currentROI = str2double(app.CurrentROIDropDown.Value);
            if isempty(currentROI)
                app.UpdateLabel.Text = 'Cannot change order: no ROI selected';
                pause(2); %pause for message to be read
                app.UpdateLabel.Text = '';
            else %shift the order of ROIs   
                if currentROI>1 %don't shift ROI if it's already at the top
                    order = 1:app.rois.num; %e.g. order = [1, 2, 3, 4...]
                    order(order==currentROI) = -1; %e.g. for currentROI=3, order = [1, 2,-1, 4...]
                    order(order==currentROI-1) = currentROI; %e.g. [1, 3,-1, 4...]
                    order(order==-1) = currentROI-1; %e.g. [1, 3, 2, 4...]
                    
                    %recreate ROIs with new order
                    app.rois.roi = app.rois.roi(:,:,order);
                    app.rois.x = app.rois.x(order,:);
                    app.rois.y = app.rois.y(order,:);
                
                    app.currentROI = currentROI-1; %keep the same ROI selected
                    app.update;
                end
            end
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            currentROI = str2double(app.CurrentROIDropDown.Value);
            app.currentROI = currentROI;
            if isempty(currentROI)
                app.UpdateLabel.Text = 'Cannot change order: no ROI selected';
                pause(2); %pause for message to be read
                app.UpdateLabel.Text = '';
            else %shift the order of ROIs   
                if currentROI<app.rois.num %don't shift ROI if it's already at the bottom
                    order = 1:app.rois.num; %e.g. order = [1, 2, 3, 4...]
                    order(order==currentROI) = -1; %e.g. for currentROI=3, order = [1, 2,-1, 4...]
                    order(order==currentROI+1) = currentROI; %e.g. [1, 2,-1, 3...]
                    order(order==-1) = currentROI+1; %e.g. [1, 2, 4, 3...]
                    
                    %recreate ROIs with new order
                    app.rois.roi = app.rois.roi(:,:,order);
                    app.rois.x = app.rois.x(order,:);
                    app.rois.y = app.rois.y(order,:);
                
                    app.currentROI = currentROI+1; %keep the same ROI selected
                    app.update;
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
              app.update;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Slider.Value = changingValue;
            app.update;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: MotioncorrectionButton
        function MotioncorrectionButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.MotioncorrectionButton = uibutton(app.GridLayout, 'push');
            app.MotioncorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @MotioncorrectionButtonPushed, true);
            app.MotioncorrectionButton.Text = 'Motion correction';
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {''; ''};
            app.showCommand = uieditfield(app.GridLayout, 'text');
            app.AddNewROIButton = uibutton(app.GridLayout, 'push');
            app.AddNewROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewROIButtonPushed, true);
            app.AddNewROIButton.Text = 'Add New ROI';
            app.LoadImageSeriesButton = uibutton(app.GridLayout, 'push');
            app.LoadImageSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageSeriesButtonPushed, true);
            app.LoadImageSeriesButton.Text = 'Load Image Series';
            app.LoadROIsButton = uibutton(app.GridLayout, 'push');
            app.LoadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButtonPushed, true);
            app.LoadROIsButton.Text = 'Load ROIs';
            app.SavePanel = uipanel(app.GridLayout);
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.saveROIpngCheckBox = uicheckbox(app.SavePanel);
            app.saveROIpngCheckBox.Text = 'save ROI .png';
            app.saveROIpngCheckBox.Value = true;
            app.saveROIsvgCheckBox = uicheckbox(app.SavePanel);
            app.saveROIsvgCheckBox.Text = 'save ROI .svg';
            app.saveROIsvgCheckBox.Value = true;
            app.savetracepngCheckBox = uicheckbox(app.SavePanel);
            app.savetracepngCheckBox.Text = 'save trace .png';
            app.savetracepngCheckBox.Value = true;
            app.savetracesvgCheckBox = uicheckbox(app.SavePanel);
            app.savetracesvgCheckBox.Text = 'save trace .svg';
            app.savetracesvgCheckBox.Value = true;
            app.saveTracescsvCheckBox = uicheckbox(app.SavePanel);
            app.saveTracescsvCheckBox.Text = 'save Trace(s).csv';
            app.CurrentROIPanel = uipanel(app.GridLayout);
            app.CurrentROIPanel.Title = 'Current ROI';
            app.CurrentROIDropDown = uidropdown(app.CurrentROIPanel);
            app.CurrentROIDropDown.Items = {};
            app.CurrentROIDropDown.Value = {};
            app.ChangeOrderLabel = uilabel(app.CurrentROIPanel);
            app.ChangeOrderLabel.Text = {'Change'; 'Order:'};
            app.upButton = uibutton(app.CurrentROIPanel, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Text = 'up';
            app.downButton = uibutton(app.CurrentROIPanel, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = 'down';
            app.DeleteButton = uibutton(app.CurrentROIPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.DeleteROILabel = uilabel(app.CurrentROIPanel);
            app.DeleteROILabel.Text = {'Delete'; 'ROI:'};
            app.ProjectionDropDown = uidropdown(app.GridLayout);
            app.ProjectionDropDown.Items = {'mean projection', 'mean + variance', 'time series'};
            app.ProjectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDownValueChanged, true);
            app.ProjectionDropDown.Value = 'mean projection';
            app.fpsLabel = uilabel(app.GridLayout);
            app.fpsLabel.Text = 'fps';
            app.currentimageseriesfpsLabel = uilabel(app.GridLayout);
            app.currentimageseriesfpsLabel.Text = 'current image series fps:';
            app.LabelDropDown = uidropdown(app.GridLayout);
            app.LabelDropDown.Items = {'labels in legend', 'labels in image'};
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelDropDownValueChanged, true);
            app.LabelDropDown.Visible = 'off';
            app.LabelDropDown.Value = 'labels in legend';
            app.DropDown_3Label = uilabel(app.GridLayout);
            app.DropDown_3Label.Text = '';
            app.UpdateLabel = uilabel(app.GridLayout);
            app.UpdateLabel.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = draw_ROIs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end